classdef Project1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Load                  matlab.ui.container.Menu
        Save                  matlab.ui.container.Menu
        SharpnessSlider       matlab.ui.control.Slider
        SharpnessSliderLabel  matlab.ui.control.Label
        ContrastSlider        matlab.ui.control.Slider
        ContrastSliderLabel   matlab.ui.control.Label
        BrightDarkSlider      matlab.ui.control.Slider
        BrightnessDarknessGammaValueLabel  matlab.ui.control.Label
        PixelMapAxes          matlab.ui.control.UIAxes
        ModAxes               matlab.ui.control.UIAxes
        OrigAxes              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        OrigImg = double([]); % Original Image
        TempImg = double([]); % Temporary Image
        ModImg = double([]); % Modified Image
        M = double(0); % Image number of rows
        N = double(0); % Image number of columns
        Gamma = double(1); % Gamma value initialized to 1
        Sharp = double(0); % Sharpness scalar multiple
        Kernel = [-1,-1,-1;-1,8,-1;-1,-1,-1]; % sharpen kernel
        Curve = double([]); % Curvature of contrast arctan()
        EndPt = double(1); % Endpoint scalar to ensure maps to 0 and 1
        s = double([]); % S variable for plotting pixel map
        sTemp = double([]); % S temp varibable for dual plotting pixel map
        r = 0:0.001:1; % R variable for plotting pixel map
    end
    
    methods (Access = private)

        function UpdateMod(app)
            %This function will always start with original image then add
            %brightness/darkness, then add contrast, then add sharpness. If
            %one section has default value then it will skip that
            %modification.
            
            %Ensure TempImg is reset/initialized to OrigImg
            app.TempImg = app.OrigImg; %reset back to orig brightness
            app.s = app.r.^app.Gamma; %reset pixel mapping
            
            %If Brighten/Darken Slider modified
            if app.Gamma ~= 1
                %gamma < 1 = brighten, gamma > 1 = darken
                app.TempImg = app.OrigImg.^app.Gamma;
                app.s = app.r.^app.Gamma; %pixel mapping
            end
            
            %If Contrast Slider modified
            if app.Curve ~= 0
                %Curve value is postive
                if app.Curve > 0
                    %finds EndPt to match curve value to map 0 to 0, implies 1 to 1 also
                    while round((app.EndPt*atan(app.Curve * (0 - .5)) + .5),3) < 0
                        app.EndPt = app.EndPt - 0.001; %decrement up until 0 maps to 0
                    end
                    %if slider moved back down to smaller positive value
                    while round((app.EndPt*atan(app.Curve * (0 - .5)) + .5),3) > 0
                        app.EndPt = app.EndPt + 0.001; %increment up until 0 maps to 0
                    end

                    %apply contrast arctan() function shifted and scaled to image
                    for i = 1:app.M %rows
                        for j = 1:app.N %columns
                            app.TempImg(i,j) = app.EndPt*atan(app.Curve*(app.TempImg(i,j) - .5)) + .5;
                        end
                    end
                    %set pixel mapping
                    if app.Gamma == 0
                        app.s = app.EndPt*atan(app.Curve*(app.r - .5)) + .5; % contrast only pixel mapping
                    else
                        app.s = app.r.^app.Gamma; %pixel mapping of just gamma
                        app.s = app.EndPt*atan(app.Curve*(app.s - .5)) + .5; %pixel mapping adding contrast
                    end
                %Curve value is negative take abs() of slider value
                else
                    %finds EndPt to match curve value to map 0 to 0, implies 1 to 1 also
                    while round((app.EndPt*tan(abs(app.Curve) * (0 - .5)) + .5),3) < 0
                        app.EndPt = app.EndPt - 0.001; %decrement up until 0 maps to 0
                    end
                    %if slider is moved back to less negative value
                    while round((app.EndPt*tan(abs(app.Curve) * (0 - .5)) + .5),3) > 0
                        app.EndPt = app.EndPt + 0.001; %increment up until 0 maps to 0
                    end
                    
                    %apply contrast tan() function shifted and scaled to image
                    for i = 1:app.M %rows
                        for j = 1:app.N %columns
                            app.TempImg(i,j) = app.EndPt*tan(abs(app.Curve)*(app.TempImg(i,j) - .5)) + .5;
                        end
                    end
                    %set pixel mapping
                    if app.Gamma == 0
                        app.s = app.EndPt*tan(abs(app.Curve)*(app.r - .5)) + .5; %contrast only pixel mapping
                    else
                        app.sTemp = app.r.^app.Gamma; %pixel mapping of just gamma
                        app.s = app.EndPt*tan(abs(app.Curve)*(app.sTemp - .5)) + .5; %pixel mapping adding contrast
                    end
                end
            end
            
            %If Sharpness Slider modified
            if app.Sharp ~= 0
                edges = conv2(app.TempImg,app.Kernel,"same"); %auto-removes excess boarders
                app.TempImg = app.TempImg + (app.Sharp*edges); %add sharpened edges to img
            end
            
            %Update plots
            plot(app.PixelMapAxes,app.r,app.s,'r',app.r,app.r,'b--'); %display mapping
            legend(app.PixelMapAxes,{'Modified Pixel Mapping','Identity Line'},'Location','southeast');
            app.ModImg = app.TempImg; %update modifed img
            imshow(app.ModImg,'Parent',app.ModAxes); %display modified img
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: Load
        function LoadMenuSelected(app, event)
            [file,path] = uigetfile({'*.tif';'*.png';'*.jpeg'},'Select an Image'); %load image
            fullfilename = fullfile(path,file); %user can change load path
            app.OrigImg = im2double(imread(fullfilename)); %read image in
            [app.M, app.N] = size(app.OrigImg); %set size of orig img
            imshow(app.OrigImg,'Parent',app.OrigAxes); %display orig img
            UpdateMod(app);
        end

        % Menu selected function: Save
        function SaveMenuSelected(app, event)
            [file,path] = uiputfile({'*.tif';'*.png';'*.jpeg'},'Save the Image'); %save image
            fullfilename = fullfile(path,file); %user can change save path
            image = uint8(app.ModImg*255); %convert to uint8 type
            imwrite(image,fullfilename); %write image to file
        end

        % Value changed function: BrightDarkSlider
        function BrightDarkSliderValueChanged(app, event)
            app.Gamma = app.BrightDarkSlider.Value; %get gamma value from slider
            UpdateMod(app);
        end

        % Value changed function: ContrastSlider
        function ContrastSliderValueChanged(app, event)
            app.Curve = app.ContrastSlider.Value; %get curve cvalue from slider
            UpdateMod(app);
        end

        % Value changed function: SharpnessSlider
        function SharpnessSliderValueChanged(app, event)
            app.Sharp = app.SharpnessSlider.Value; %get sharpness value from slider
            UpdateMod(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Load = uimenu(app.UIFigure);
            app.Load.MenuSelectedFcn = createCallbackFcn(app, @LoadMenuSelected, true);
            app.Load.Text = 'Load Image';
            app.Save = uimenu(app.UIFigure);
            app.Save.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.Save.Text = 'Save Image';
            app.OrigAxes = uiaxes(app.UIFigure);
            title(app.OrigAxes, 'Original Image')
            app.OrigAxes.XTick = [];
            app.OrigAxes.YTick = [];
            app.ModAxes = uiaxes(app.UIFigure);
            title(app.ModAxes, 'Modified Image')
            app.ModAxes.XTick = [];
            app.ModAxes.YTick = [];
            app.PixelMapAxes = uiaxes(app.UIFigure);
            title(app.PixelMapAxes, 'Pixel Mapping')
            app.PixelMapAxes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.PixelMapAxes.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.PixelMapAxes.ZTick = 0;
            app.PixelMapAxes.ZTickLabel = {'0'};
            app.BrightnessDarknessGammaValueLabel = uilabel(app.UIFigure);
            app.BrightnessDarknessGammaValueLabel.Text = {'Brightness / Darkness'; '(Gamma Value)'};
            app.BrightDarkSlider = uislider(app.UIFigure);
            app.BrightDarkSlider.Limits = [0.1 4];
            app.BrightDarkSlider.ValueChangedFcn = createCallbackFcn(app, @BrightDarkSliderValueChanged, true);
            app.BrightDarkSlider.Value = 1;
            app.ContrastSliderLabel = uilabel(app.UIFigure);
            app.ContrastSliderLabel.Text = 'Contrast';
            app.ContrastSlider = uislider(app.UIFigure);
            app.ContrastSlider.Limits = [-3 20];
            app.ContrastSlider.ValueChangedFcn = createCallbackFcn(app, @ContrastSliderValueChanged, true);
            app.SharpnessSliderLabel = uilabel(app.UIFigure);
            app.SharpnessSliderLabel.Text = 'Sharpness';
            app.SharpnessSlider = uislider(app.UIFigure);
            app.SharpnessSlider.Limits = [0 1];
            app.SharpnessSlider.MajorTicks = [0 0.05 0.1 0.15 0.2 0.25 0.3 0.35 0.4 0.45 0.5 0.55 0.6 0.65 0.7 0.75 0.8 0.85 0.9 0.95 1];
            app.SharpnessSlider.ValueChangedFcn = createCallbackFcn(app, @SharpnessSliderValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Project1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end