classdef RGB_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        OpenImageMenu     matlab.ui.container.Menu
        SaveImageMenu     matlab.ui.container.Menu
        UIAxes            matlab.ui.control.UIAxes
        RedSliderLabel    matlab.ui.control.Label
        RedSlider         matlab.ui.control.Slider
        GreenSliderLabel  matlab.ui.control.Label
        GreenSlider       matlab.ui.control.Slider
        BlueSliderLabel   matlab.ui.control.Label
        BlueSlider        matlab.ui.control.Slider
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: RedSlider
        function RedSliderValueChanged(app, event)
            
            % Get RGB colors and put together for rgbcolor
            R = app.RedSlider.Value;
            G = app.GreenSlider.Value;
            B = app.BlueSlider.Value;
            rgbcolor = [R,G,B]/255; 
            
            % Set the axes color to rgbcolor
%             set(app.UIAxes,'Color',rgbcolor); 
            
            % Convert to HSV and get hue from RGB sliders 
            hsvcolor = rgb2hsv(rgbcolor); 
            h = hsvcolor(1); 
            
            % Get the loaded image and use as grayscale 
            image = getappdata(app.UIAxes,'stored_image'); 
            [M,N] = size(image); 
            
            % Construct an HSV image with "image" as the value 
            hue = h*ones(M,N); 
            sat = ones(M,N); 
            hsv_image = zeros(M,N,3); 
            hsv_image(:,:,1) = hue; 
            hsv_image(:,:,2) = sat; 
            hsv_image(:,:,3) = image; 
            
            % Convert to RGB, display, and save in app data
            rgb_image = hsv2rgb(hsv_image); 
            imshow(rgb_image,'Parent',app.UIAxes); 
            setappdata(app.UIAxes,'rgb_image',rgb_image);
            
        end

        % Value changed function: GreenSlider
        function GreenSliderValueChanged(app, event)
            % Get RGB colors and put together for rgbcolor
            R = app.RedSlider.Value;
            G = app.GreenSlider.Value;
            B = app.BlueSlider.Value;
            rgbcolor = [R,G,B]/255; 
            
            % Set the axes color to rgbcolor
%             set(app.UIAxes,'Color',rgbcolor); 
            
            % Convert to HSV and get hue from RGB sliders 
            hsvcolor = rgb2hsv(rgbcolor); 
            h = hsvcolor(1); 
            
            % Get the loaded image and use as grayscale 
            image = getappdata(app.UIAxes,'stored_image'); 
            [M,N] = size(image); 
            
            % Construct an HSV image with "image" as the value 
            hue = h*ones(M,N); 
            sat = ones(M,N); 
            hsv_image = zeros(M,N,3); 
            hsv_image(:,:,1) = hue; 
            hsv_image(:,:,2) = sat; 
            hsv_image(:,:,3) = image; 
            
            % Convert to RGB, display, and save in app data
            rgb_image = hsv2rgb(hsv_image); 
            imshow(rgb_image,'Parent',app.UIAxes); 
            setappdata(app.UIAxes,'rgb_image',rgb_image);
            
            
        end

        % Value changed function: BlueSlider
        function BlueSliderValueChanged(app, event)
            % Get RGB colors and put together for rgbcolor
            R = app.RedSlider.Value;
            G = app.GreenSlider.Value;
            B = app.BlueSlider.Value;
            rgbcolor = [R,G,B]/255; 
            
            % Set the axes color to rgbcolor
%             set(app.UIAxes,'Color',rgbcolor); 
            
            % Convert to HSV and get hue from RGB sliders 
            hsvcolor = rgb2hsv(rgbcolor); 
            h = hsvcolor(1); 
            s = hsvcolor(2); 
            
            % Get the loaded image and use as grayscale 
            image = getappdata(app.UIAxes,'stored_image'); 
            [M,N] = size(image); 
            
            % Construct an HSV image with "image" as the value 
            hue = h*ones(M,N); 
            sat = s*ones(M,N); 
            hsv_image = zeros(M,N,3); 
            hsv_image(:,:,1) = hue; 
            hsv_image(:,:,2) = sat; 
            hsv_image(:,:,3) = image; 
            
            % Convert to RGB, display, and save in app data
            rgb_image = hsv2rgb(hsv_image); 
            imshow(rgb_image,'Parent',app.UIAxes); 
            setappdata(app.UIAxes,'rgb_image',rgb_image);
            
        end

        % Menu selected function: OpenImageMenu
        function OpenImageMenuSelected(app, event)
            
            % Open image and save as "image" 
            [filename,~] = uigetfile({'*.tif'},'Open Image'); 
            image = im2double(imread(filename)); 
            
            % Display image and save within GUI
            imshow(image,'Parent',app.UIAxes); 
            setappdata(app.UIAxes,'stored_image',image); 
            
            
        end

        % Menu selected function: SaveImageMenu
        function SaveImageMenuSelected(app, event)
            
            % Allow the user to select a file name
            [filename,~] = uiputfile({'*.tif'},'Save Image'); 
            
            % Convert image to uint8 and save 
            image = getappdata(app.UIAxes,'rgb_image'); 
            image2 = uint8(image*255); 
            imwrite(image2,filename); 
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OpenImageMenu = uimenu(app.UIFigure);
            app.OpenImageMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenImageMenuSelected, true);
            app.OpenImageMenu.Text = 'Open Image';
            app.SaveImageMenu = uimenu(app.UIFigure);
            app.SaveImageMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveImageMenuSelected, true);
            app.SaveImageMenu.Text = 'Save Image';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.RedSliderLabel = uilabel(app.UIFigure);
            app.RedSliderLabel.Text = 'Red';
            app.RedSlider = uislider(app.UIFigure);
            app.RedSlider.Limits = [0 255];
            app.RedSlider.ValueChangedFcn = createCallbackFcn(app, @RedSliderValueChanged, true);
            app.GreenSliderLabel = uilabel(app.UIFigure);
            app.GreenSliderLabel.Text = 'Green';
            app.GreenSlider = uislider(app.UIFigure);
            app.GreenSlider.Limits = [0 255];
            app.GreenSlider.ValueChangedFcn = createCallbackFcn(app, @GreenSliderValueChanged, true);
            app.BlueSliderLabel = uilabel(app.UIFigure);
            app.BlueSliderLabel.Text = 'Blue';
            app.BlueSlider = uislider(app.UIFigure);
            app.BlueSlider.Limits = [0 255];
            app.BlueSlider.ValueChangedFcn = createCallbackFcn(app, @BlueSliderValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RGB_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end