classdef sumEx < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Number1EditFieldLabel  matlab.ui.control.Label
        Number1EditField       matlab.ui.control.NumericEditField
        Number2EditFieldLabel  matlab.ui.control.Label
        Number2EditField       matlab.ui.control.NumericEditField
        SumEditFieldLabel      matlab.ui.control.Label
        SumEditField           matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        N1 = double(0); % Number 1
        N2 = double(0); % Number 2
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Number1EditField
        function Number1EditFieldValueChanged(app, event)
            app.N1 = app.Number1EditField.Value;
            app.SumEditField.Value = app.N1 + app.N2;
        end

        % Value changed function: Number2EditField
        function Number2EditFieldValueChanged(app, event)
            app.N2 = app.Number2EditField.Value;
            app.SumEditField.Value = app.N1 + app.N2;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Number1EditFieldLabel = uilabel(app.UIFigure);
            app.Number1EditFieldLabel.Text = 'Number 1';
            app.Number1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Number1EditField.ValueChangedFcn = createCallbackFcn(app, @Number1EditFieldValueChanged, true);
            app.Number2EditFieldLabel = uilabel(app.UIFigure);
            app.Number2EditFieldLabel.Text = 'Number 2';
            app.Number2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Number2EditField.ValueChangedFcn = createCallbackFcn(app, @Number2EditFieldValueChanged, true);
            app.SumEditFieldLabel = uilabel(app.UIFigure);
            app.SumEditFieldLabel.Text = 'Sum';
            app.SumEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sumEx

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end