classdef Project2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Load                       matlab.ui.container.Menu
        Save                       matlab.ui.container.Menu
        ButtonGroup                matlab.ui.container.ButtonGroup
        Color3Button               matlab.ui.control.ToggleButton
        Color2Button               matlab.ui.control.ToggleButton
        Color1Button               matlab.ui.control.ToggleButton
        ResetAllButton             matlab.ui.control.Button
        SegmentButton              matlab.ui.control.Button
        IntensityLower             matlab.ui.control.Slider
        IntensityLowerLimitLabel   matlab.ui.control.Label
        IntensityUpper             matlab.ui.control.Slider
        IntensityUpperLimitLabel   matlab.ui.control.Label
        SatLower                   matlab.ui.control.Slider
        SaturationLowerLimitLabel  matlab.ui.control.Label
        SatUpper                   matlab.ui.control.Slider
        SaturationUpperLimitSliderLabel  matlab.ui.control.Label
        HueLower                   matlab.ui.control.Slider
        HueLowerLimitSliderLabel   matlab.ui.control.Label
        HueUpper                   matlab.ui.control.Slider
        HueUpperLimitLabel         matlab.ui.control.Label
        SegAxes                    matlab.ui.control.UIAxes
        OrigAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        OrigImg = double([]); % Original Image
        TempSeg = double([]); % Temporary Segmented Image
        SegImg = double([]); % Modified Image
        M = double(0); % Image number of rows
        N = double(0); % Image number of columns
        O = double(0); % Image number of sheets
        
        H = double([]); % Hue
        HUp1 = 1; % Hue Upper Limit 1
        HLow1 = 0; % Hue Lower Limit 1
        HUp2 = 1; % Hue Upper Limit 2
        HLow2 = 0; % Hue Lower Limit 2
        HUp3 = 1; % Hue Upper Limit 3
        HLow3 = 0; % Hue Lower Limit 3
        
        S = double([]); % Saturation
        SUp1 = 1; %Saturation Upper Limit 1
        SLow1 = 0; % Saturation Lower Limit 1
        SUp2 = 1; %Saturation Upper Limit 2
        SLow2 = 0; % Saturation Lower Limit 2
        SUp3 = 1; %Saturation Upper Limit 3
        SLow3 = 0; % Saturation Lower Limit 3
        
        I = double([]); % Intensity
        IUp1 = 1; % Intensity Upper Limit 1
        ILow1 = 0; % intensity Lower Limit 1
        IUp2 = 1; % Intensity Upper Limit 2
        ILow2 = 0; % intensity Lower Limit 2
        IUp3 = 1; % Intensity Upper Limit 3
        ILow3 = 0; % intensity Lower Limit 3
        X = 0; % Color 1 switch
        Y = 0; % Color 2 switch
        Z = 0; % Color 3 switch
        Color1 = double([]) % Color 1 segmentation
        Color2 = double([]) % Color 2 segmentation
        Color3 = double([]) % Color 3 segmentation
    end
    
    methods (Access = private)
        
        %Segements image based on parameters given
        function Segment(app, upperH, lowerH, upperS, lowerS, upperI, lowerI)
            app.TempSeg = 0.5*ones(size(app.OrigImg));
            for r = 1:app.M
                for c = 1:app.N
                    if ((app.H(r,c) >= lowerH && app.H(r,c) <= upperH) && (app.S(r,c) >= lowerS && app.S(r,c) <= upperS) && (app.I(r,c) >= lowerI && app.I(r,c) <= upperI))
                        app.TempSeg(r,c,1:3) = app.OrigImg(r,c,1:3); %1:3 because need H,S,& I values at (r,c)
                    else
                        app.TempSeg(r,c,1:3) = 0.5; %make HSI value at (r,c) = gray
                    end
                end
                % disp(r)
            end
            imshow(app.TempSeg,'Parent',app.SegAxes); %display temporary segmented img
        end
        
        %Checks which button is selected and updates axes and corresponding image variable for that color selection
        function Update(app)
            %if Color1 Button Selected and slider changed
            if app.X == 1
                title(app.SegAxes, 'Color1 Segmented Image');
                app.Color1Button.Text = 'Processing...'; % Change button name to "Processing"
                app.Color1Button.FontWeight = 'bold';
                drawnow; % force button update
                Segment(app,app.HUp1, app.HLow1, app.SUp1, app.SLow1, app.IUp1, app.ILow1);
                app.Color1 = app.TempSeg;
                app.Color1Button.FontWeight = 'normal';
                app.Color1Button.Text = 'Color1'; % Change button name
                drawnow; % force button update
                
            %if Color2 Button Selected and slider changed
            elseif app.Y == 1
                title(app.SegAxes, 'Color2 Segmented Image');
                app.Color2Button.Text = 'Processing...'; % Change button name to "Processing"
                app.Color2Button.FontWeight = 'bold';
                drawnow; % force button update
                Segment(app,app.HUp2, app.HLow2, app.SUp2, app.SLow2, app.IUp2, app.ILow2);
                app.Color2 = app.TempSeg;
                app.Color2Button.FontWeight = 'normal';
                app.Color2Button.Text = 'Color2'; % Change button name
                drawnow; % force button update
                
            %if Color3 Button Selected and slider changed
            else % Z == 1
                title(app.SegAxes, 'Color3 Segmented Image');
                app.Color3Button.Text = 'Processing...'; % Change button name to "Processing"
                app.Color3Button.FontWeight = 'bold';
                drawnow; % force button update
                Segment(app,app.HUp3, app.HLow3, app.SUp3, app.SLow3, app.IUp3, app.ILow3);
                app.Color3 = app.TempSeg;
                app.Color3Button.FontWeight = 'normal';
                app.Color3Button.Text = 'Color3'; % Change button name
                drawnow; % force button update
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: Load
        function LoadMenuSelected(app, event)
            [file,path] = uigetfile({'*.tif';'*.png';'*.jpg'},'Select an Image'); %load image
            fullfilename = fullfile(path,file); %user can change load path
            app.OrigImg = im2double(imread(fullfilename)); %read image in
            [app.M, app.N, app.O] = size(app.OrigImg); %set size of orig img
            [app.H,app.S,app.I] = rgb2hsv(app.OrigImg);
            app.Color1 = 0.5*ones(size(app.OrigImg)); %initialize Color1 to gray
            app.Color2 = 0.5*ones(size(app.OrigImg)); %initialize Color2 to gray
            app.Color3 = 0.5*ones(size(app.OrigImg)); %initialize Color3 to gray
            imshow(app.OrigImg,'Parent',app.OrigAxes); %display orig img
            imshow(app.OrigImg,'Parent',app.SegAxes); %display orig on mod img axes
        end

        % Menu selected function: Save
        function SaveMenuSelected(app, event)
            [file,path] = uiputfile({'*.tif';'*.png';'*.jpg'},'Save the Image'); %save image
            fullfilename = fullfile(path,file); %user can change save path
            image = uint8(app.SegImg*255); %convert to uint8 type
            imwrite(image,fullfilename); %write image to file
        end

        % Value changed function: HueUpper
        function HueUpperValueChanged(app, event)
            if app.Color1Button.Value
                app.X = 1; app.Y = 0; app.Z = 0;
                app.HUp1 = app.HueUpper.Value; %get hue upper value from slider
                app.HueLower.Limits(2) = app.HueUpper.Value; % update slider limits
                Update(app);
                
            elseif app.Color2Button.Value
                app.X = 0; app.Y = 1; app.Z = 0;
                app.HUp2 = app.HueUpper.Value; %get hue upper value from slider
                app.HueLower.Limits(2) = app.HueUpper.Value; % update slider limits
                Update(app);
                
            else %app.Color3Button.Value
                app.X = 0; app.Y = 0; app.Z = 1;
                app.HUp3 = app.HueUpper.Value; %get hue upper value from slider
                app.HueLower.Limits(2) = app.HueUpper.Value; % update slider limits
                Update(app);
            end
        end

        % Value changed function: HueLower
        function HueLowerValueChanged(app, event)
            if app.Color1Button.Value
                app.X = 1; app.Y = 0; app.Z = 0;
                app.HLow1 = app.HueLower.Value; %get hue lower value from slider
                app.HueUpper.Limits(1) = app.HueLower.Value; % update slider limits
                Update(app);

            elseif app.Color2Button.Value
                app.X = 0; app.Y = 1; app.Z = 0;
                app.HLow2 = app.HueLower.Value; %get hue lower value from slider
                app.HueUpper.Limits(1) = app.HueLower.Value; % update slider limits
                Update(app);

            else %app.Color3Button.Value
                app.X = 0; app.Y = 0; app.Z = 1;
                app.HLow3 = app.HueLower.Value; %get hue lower value from slider
                app.HueUpper.Limits(1) = app.HueLower.Value; % update slider limits
                Update(app);
            end
        end

        % Value changed function: SatUpper
        function SatUpperValueChanged(app, event)
            if app.Color1Button.Value
                app.X = 1; app.Y = 0; app.Z = 0;
                app.SUp1 = app.SatUpper.Value; %get sat upper value from slider
                app.SatLower.Limits(2) = app.SatUpper.Value; % update slider limits
                Update(app);

            elseif app.Color2Button.Value
                app.X = 0; app.Y = 1; app.Z = 0;
                app.SUp2 = app.SatUpper.Value; %get sat upper value from slider
                app.SatLower.Limits(2) = app.SatUpper.Value; % update slider limits
                Update(app);

            else %app.Color3Button.Value
                app.X = 0; app.Y = 0; app.Z = 1;
                app.SUp3 = app.SatUpper.Value; %get sat upper value from slider
                app.SatLower.Limits(2) = app.SatUpper.Value; % update slider limits
                Update(app);
            end
        end

        % Value changed function: SatLower
        function SatLowerValueChanged(app, event)
            if app.Color1Button.Value
                app.X = 1; app.Y = 0; app.Z = 0;
                app.SLow1 = app.SatLower.Value; %get sat lower value from slider
                app.SatUpper.Limits(1) = app.SatLower.Value; % update slider limits
                Update(app);

            elseif app.Color2Button.Value
                app.X = 0; app.Y = 1; app.Z = 0;
                app.SLow2 = app.SatLower.Value; %get sat lower value from slider
                app.SatUpper.Limits(1) = app.SatLower.Value; % update slider limits
                Update(app);

            else %app.Color3Button.Value
                app.X = 0; app.Y = 0; app.Z = 1;
                app.SLow3 = app.SatLower.Value; %get sat lower value from slider
                app.SatUpper.Limits(1) = app.SatLower.Value; % update slider limits
                Update(app);
            end
        end

        % Value changed function: IntensityUpper
        function IntensityUpperValueChanged(app, event)
            if app.Color1Button.Value
                app.X = 1; app.Y = 0; app.Z = 0;
                app.IUp1 = app.IntensityUpper.Value; %get intensity upper value from slider
                app.IntensityLower.Limits(2) = app.IntensityUpper.Value; % update slider limits
                Update(app);

            elseif app.Color2Button.Value
                app.X = 0; app.Y = 1; app.Z = 0;
                app.IUp2 = app.IntensityUpper.Value; %get intensity upper value from slider
                app.IntensityLower.Limits(2) = app.IntensityUpper.Value; % update slider limits
                Update(app);

            else %app.Color3Button.Value
                app.X = 0; app.Y = 0; app.Z = 1;
                app.IUp3 = app.IntensityUpper.Value; %get intensity upper value from slider
                app.IntensityLower.Limits(2) = app.IntensityUpper.Value; % update slider limits
                Update(app);
            end
        end

        % Value changed function: IntensityLower
        function IntensityLowerValueChanged(app, event)
            if app.Color1Button.Value
                app.X = 1; app.Y = 0; app.Z = 0;
                app.ILow1 = app.IntensityLower.Value; %get intensity lower value from slider
                app.IntensityUpper.Limits(1) = app.IntensityLower.Value; % update slider limits
                Update(app);

            elseif app.Color2Button.Value
                app.X = 0; app.Y = 1; app.Z = 0;
                app.ILow2 = app.IntensityLower.Value; %get intensity lower value from slider
                app.IntensityUpper.Limits(1) = app.IntensityLower.Value; % update slider limits
                Update(app);

            else %app.Color3Button.Value
                app.X = 0; app.Y = 0; app.Z = 1;
                app.ILow3 = app.IntensityLower.Value; %get intensity lower value from slider
                app.IntensityUpper.Limits(1) = app.IntensityLower.Value; % update slider limits
                Update(app);
            end
        end

        % Button pushed function: SegmentButton
        function SegmentButtonPushed(app, event)
            title(app.SegAxes, 'Combined Segmented Image');
            app.SegmentButton.Text = 'Processing...'; % Change button name to "Processing"
            app.SegmentButton.FontWeight = 'bold';
            drawnow; % force button to update
            if app.Color1 == app.OrigImg
                app.Color1(:,:,1:3) = 0.5; %reset to all gray if not modified
            end
            if app.Color2 == app.OrigImg
                app.Color2(:,:,1:3) = 0.5; %reset to all gray if not modified
            end
            if app.Color3 == app.OrigImg
                app.Color3(:,:,1:3) = 0.5; %reset to all gray if not modified
            end
            %Combine modified segmented images
            for r = 1:app.M
                for c = 1:app.N
                    if app.Color1(r,c,1:3) ~= 0.5
                        app.SegImg(r,c,1:3) = app.Color1(r,c,1:3);
                    elseif app.Color2(r,c,1:3) ~= 0.5
                        app.SegImg(r,c,1:3) = app.Color2(r,c,1:3);
                    elseif app.Color3(r,c,1:3) ~= 0.5
                        app.SegImg(r,c,1:3) = app.Color3(r,c,1:3);
                    else
                        app.SegImg(r,c,1:3) = 0.5; % gray
                    end
                end
            end
            app.SegmentButton.FontWeight = 'normal';
            app.SegmentButton.Text = 'Segment'; % Change button name
            drawnow; % force button to update
            imshow(app.SegImg,'Parent',app.SegAxes); %display modified img
        end

        % Button pushed function: ResetAllButton
        function ResetAllButtonPushed(app, event)
            imshow(app.OrigImg,'Parent',app.SegAxes); %display original img on mod axes
            
            app.HUp1 = 1; % reset Hue upper variable 1
            app.HLow1 = 0; % reset Hue lower variable 1
            app.HUp2 = 1; % reset Hue upper variable 2
            app.HLow2 = 0; % reset Hue lower variable 2
            app.HUp3 = 1; % reset Hue upper variable 3
            app.HLow3 = 0; % reset Hue lower variable 3
            app.HueUpper.Limits(1) = 0; % reset slider 
            app.HueLower.Limits(2) = 1; % reset slider
            app.HueUpper.Value = 1; % reset slider value back to default
            app.HueLower.Value = 0; % reset slider value back to default

            app.SUp1 = 1; % reset Saturation upper variable 1
            app.SLow1 = 0; % reset Saturation upper variable 1
            app.SUp2 = 1; % reset Saturation upper variable 2
            app.SLow2 = 0; % reset Saturation upper variable 2
            app.SUp3 = 1; % reset Saturation upper variable 3
            app.SLow3 = 0; % reset Saturation upper variable 3
            app.SatUpper.Limits(1) = 0; % reset slider
            app.SatLower.Limits(2) = 1; % reset slider
            app.SatUpper.Value = 1; % reset slider value back to default
            app.SatLower.Value = 0; % reset slider value back to default
            
            app.IUp1 = 1; % reset Intensity upper variable 1
            app.ILow1 = 0; % reset Intensity upper variable 1
            app.IUp2 = 1; % reset Intensity upper variable 2
            app.ILow2 = 0; % reset Intensity upper variable 2
            app.IUp3 = 1; % reset Intensity upper variable 3
            app.ILow3 = 0; % reset Intensity upper variable 3
            app.IntensityUpper.Limits(1) = 0; % reset slider
            app.IntensityLower.Limits(2) = 1; % reset slider
            app.IntensityUpper.Value = 1; % reset slider value back to default
            app.IntensityLower.Value = 0; % reset slider value back to default
            
            app.Color1(:,:,1:3) = 0.5; % reset to gray
            app.Color2(:,:,1:3) = 0.5; % reset to gray
            app.Color3(:,:,1:3) = 0.5; % reset to gray
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
             if app.Color1Button.Value
                title(app.SegAxes, 'Color 1 Segmented Image');
                if app.Color1(:,:,1:3) == 0.5
                    imshow(app.OrigImg,'Parent',app.SegAxes); %display original img on mod axes
                else
                    imshow(app.Color1,'Parent',app.SegAxes); %display Color1 img on mod axes
                end
                
                app.HueUpper.Limits(1) = app.HLow1; % reset slider to previous selection
                app.HueLower.Limits(2) = app.HUp1; % reset slider to previous selection
                app.HueUpper.Value = app.HUp1; % reset slider value back to previous selection
                app.HueLower.Value = app.HLow1; % reset slider value back to previous selection
                
                app.SatUpper.Limits(1) = app.SLow1; % reset slider to previous selection
                app.SatLower.Limits(2) = app.SUp1; % reset slider to previous selection
                app.SatUpper.Value = app.SUp1; % reset slider value back to previous selection
                app.SatLower.Value = app.SLow1; % reset slider value back to previous selection
                
                app.IntensityUpper.Limits(1) = app.ILow1; % reset slider to previous selection
                app.IntensityLower.Limits(2) = app.IUp1; % reset slider to previous selection
                app.IntensityUpper.Value = app.IUp1; % reset slider value back to previous selection
                app.IntensityLower.Value = app.ILow1; % reset slider value back to previous selection

             elseif app.Color2Button.Value
                title(app.SegAxes, 'Color 2 Segmented Image');
                if app.Color2(:,:,1:3) == 0.5
                    imshow(app.OrigImg,'Parent',app.SegAxes); %display original img on mod axes
                else
                    imshow(app.Color2,'Parent',app.SegAxes); %display Color2 img on mod axes
                end
                
                app.HueUpper.Limits(1) = app.HLow2; % reset slider to previous selection
                app.HueLower.Limits(2) = app.HUp2; % reset slider to previous selection
                app.HueUpper.Value = app.HUp2; % reset slider value back to previous selection
                app.HueLower.Value = app.HLow2; % reset slider value back to previous selection
                
                app.SatUpper.Limits(1) = app.SLow2; % reset slider to previous selection
                app.SatLower.Limits(2) = app.SUp2; % reset slider to previous selection
                app.SatUpper.Value = app.SUp2; % reset slider value back to previous selection
                app.SatLower.Value = app.SLow2; % reset slider value back to previous selection
                
                app.IntensityUpper.Limits(1) = app.ILow2; % reset slider to previous selection
                app.IntensityLower.Limits(2) = app.IUp2; % reset slider to previous selection
                app.IntensityUpper.Value = app.IUp2; % reset slider value back to previous selection
                app.IntensityLower.Value = app.ILow2; % reset slider value back to previous selection

             else %app.Color3Button.Value
                title(app.SegAxes, 'Color 3 Segmented Image');
                if app.Color3(:,:,1:3) == 0.5
                    imshow(app.OrigImg,'Parent',app.SegAxes); %display original img on mod axes
                else
                    imshow(app.Color3,'Parent',app.SegAxes); %display Color3 img on mod axes
                end
                
                app.HueUpper.Limits(1) = app.HLow3; % reset slider to previous selection
                app.HueLower.Limits(2) = app.HUp3; % reset slider to previous selection
                app.HueUpper.Value = app.HUp3; % reset slider value back to previous selection
                app.HueLower.Value = app.HLow3; % reset slider value back to previous selection
                
                app.SatUpper.Limits(1) = app.SLow3; % reset slider to previous selection
                app.SatLower.Limits(2) = app.SUp3; % reset slider to previous selection
                app.SatUpper.Value = app.SUp3; % reset slider value back to previous selection
                app.SatLower.Value = app.SLow3; % reset slider value back to previous selection
                
                app.IntensityUpper.Limits(1) = app.ILow3; % reset slider to previous selection
                app.IntensityLower.Limits(2) = app.IUp3; % reset slider to previous selection
                app.IntensityUpper.Value = app.IUp3; % reset slider value back to previous selection
                app.IntensityLower.Value = app.ILow3; % reset slider value back to previous selection
             end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Load = uimenu(app.UIFigure);
            app.Load.MenuSelectedFcn = createCallbackFcn(app, @LoadMenuSelected, true);
            app.Load.Text = 'Load Image';
            app.Save = uimenu(app.UIFigure);
            app.Save.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.Save.Text = 'Save Image';
            app.OrigAxes = uiaxes(app.UIFigure);
            title(app.OrigAxes, 'Original Image')
            app.OrigAxes.XTick = [];
            app.OrigAxes.YTick = [];
            app.SegAxes = uiaxes(app.UIFigure);
            title(app.SegAxes, 'Color 1 Segmented Image')
            app.SegAxes.XTick = [];
            app.SegAxes.YTick = [];
            app.HueUpperLimitLabel = uilabel(app.UIFigure);
            app.HueUpperLimitLabel.Text = 'Hue Upper Limit';
            app.HueUpper = uislider(app.UIFigure);
            app.HueUpper.Limits = [0 1];
            app.HueUpper.ValueChangedFcn = createCallbackFcn(app, @HueUpperValueChanged, true);
            app.HueUpper.Value = 1;
            app.HueLowerLimitSliderLabel = uilabel(app.UIFigure);
            app.HueLowerLimitSliderLabel.Text = 'Hue Lower Limit';
            app.HueLower = uislider(app.UIFigure);
            app.HueLower.Limits = [0 1];
            app.HueLower.ValueChangedFcn = createCallbackFcn(app, @HueLowerValueChanged, true);
            app.SaturationUpperLimitSliderLabel = uilabel(app.UIFigure);
            app.SaturationUpperLimitSliderLabel.Text = 'Saturation Upper Limit';
            app.SatUpper = uislider(app.UIFigure);
            app.SatUpper.Limits = [0 1];
            app.SatUpper.ValueChangedFcn = createCallbackFcn(app, @SatUpperValueChanged, true);
            app.SatUpper.Value = 1;
            app.SaturationLowerLimitLabel = uilabel(app.UIFigure);
            app.SaturationLowerLimitLabel.Text = 'Saturation Lower Limit';
            app.SatLower = uislider(app.UIFigure);
            app.SatLower.Limits = [0 1];
            app.SatLower.ValueChangedFcn = createCallbackFcn(app, @SatLowerValueChanged, true);
            app.IntensityUpperLimitLabel = uilabel(app.UIFigure);
            app.IntensityUpperLimitLabel.Text = 'Intensity Upper Limit';
            app.IntensityUpper = uislider(app.UIFigure);
            app.IntensityUpper.Limits = [0 1];
            app.IntensityUpper.ValueChangedFcn = createCallbackFcn(app, @IntensityUpperValueChanged, true);
            app.IntensityUpper.Value = 1;
            app.IntensityLowerLimitLabel = uilabel(app.UIFigure);
            app.IntensityLowerLimitLabel.Text = 'Intensity Lower Limit';
            app.IntensityLower = uislider(app.UIFigure);
            app.IntensityLower.Limits = [0 1];
            app.IntensityLower.ValueChangedFcn = createCallbackFcn(app, @IntensityLowerValueChanged, true);
            app.SegmentButton = uibutton(app.UIFigure, 'push');
            app.SegmentButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentButtonPushed, true);
            app.SegmentButton.Text = 'Segment';
            app.ResetAllButton = uibutton(app.UIFigure, 'push');
            app.ResetAllButton.ButtonPushedFcn = createCallbackFcn(app, @ResetAllButtonPushed, true);
            app.ResetAllButton.Text = 'Reset All';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.Color1Button = uitogglebutton(app.ButtonGroup);
            app.Color1Button.Text = 'Color 1';
            app.Color1Button.Value = true;
            app.Color2Button = uitogglebutton(app.ButtonGroup);
            app.Color2Button.Text = 'Color 2';
            app.Color3Button = uitogglebutton(app.ButtonGroup);
            app.Color3Button.Text = 'Color 3';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Project2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end