classdef Project2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Load                       matlab.ui.container.Menu
        Save                       matlab.ui.container.Menu
        SegmentButton              matlab.ui.control.Button
        IntensityLower             matlab.ui.control.Slider
        IntensityLowerLimitLabel   matlab.ui.control.Label
        IntensityUpper             matlab.ui.control.Slider
        IntensityUpperLimitLabel   matlab.ui.control.Label
        SatLower                   matlab.ui.control.Slider
        SaturationLowerLimitLabel  matlab.ui.control.Label
        SatUpper                   matlab.ui.control.Slider
        SaturationUpperLimitSliderLabel  matlab.ui.control.Label
        HueLower                   matlab.ui.control.Slider
        HueLowerLimitSliderLabel   matlab.ui.control.Label
        HueUpper                   matlab.ui.control.Slider
        HueUpperLimitLabel         matlab.ui.control.Label
        ModAxes                    matlab.ui.control.UIAxes
        OrigAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        OrigImg = double([]); % Original Image
        ModImg = double([]); % Modified Image
        M = double(0); % Image number of rows
        N = double(0); % Image number of columns
        O = double(0); % Image number of sheets
        
        H = double([]); % Hue
        HUp = 1; % Hue Upper Limit
        HLow = 0; % Hue Lower Limit
        
        S = double([]); % Saturation
        SUp = 1; %Saturation Upper Limit
        SLow = 0; % Saturation Lower Limit
        
        I = double([]); % Intensity
        IUp = 1; % Intensity Upper Limit
        ILow = 0; % intensity Lower Limit
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: Load
        function LoadMenuSelected(app, event)
            [file,path] = uigetfile({'*.tif';'*.png';'*.jpeg'},'Select an Image'); %load image
            fullfilename = fullfile(path,file); %user can change load path
            app.OrigImg = im2double(imread(fullfilename)); %read image in
            [app.M, app.N, app.O] = size(app.OrigImg); %set size of orig img
            [app.H,app.S,app.I] = rgb2hsv(app.OrigImg);
            imshow(app.OrigImg,'Parent',app.OrigAxes); %display orig img
            imshow(app.OrigImg,'Parent',app.ModAxes); %display orig on mod img axes
        end

        % Menu selected function: Save
        function SaveMenuSelected(app, event)
            [file,path] = uiputfile({'*.tif';'*.png';'*.jpeg'},'Save the Image'); %save image
            fullfilename = fullfile(path,file); %user can change save path
            image = uint8(app.ModImg*255); %convert to uint8 type
            imwrite(image,fullfilename); %write image to file
        end

        % Value changed function: HueUpper
        function HueUpperValueChanged(app, event)
            app.HUp = app.HueUpper.Value; %get hue upper value from slider
            app.HueLower.Limits(2) = app.HueUpper.Value;
        end

        % Value changed function: HueLower
        function HueLowerValueChanged(app, event)
            app.HLow = app.HueLower.Value; %get hue lower value from slider
            app.HueUpper.Limits(1) = app.HueLower.Value;
        end

        % Value changed function: SatUpper
        function SatUpperValueChanged(app, event)
            app.SUp = app.SatUpper.Value; %get sat upper value from slider
            app.SatLower.Limits(2) = app.SatUpper.Value;
        end

        % Value changed function: SatLower
        function SatLowerValueChanged(app, event)
            app.SLow = app.SatLower.Value; %get sat lower value from slider
            app.SatUpper.Limits(1) = app.SatLower.Value;
        end

        % Value changed function: IntensityUpper
        function IntensityUpperValueChanged(app, event)
            app.IUp = app.IntensityUpper.Value; %get intensity upper value from slider
            app.IntensityLower.Limits(2) = app.IntensityUpper.Value;
        end

        % Value changed function: IntensityLower
        function IntensityLowerValueChanged(app, event)
            app.ILow = app.IntensityLower.Value; %get intensity lower value from slider
            app.IntensityUpper.Limits(1) = app.IntensityLower.Value;
        end

        % Button pushed function: SegmentButton
        function SegmentButtonPushed(app, event)
            for r = 1:app.M
                for c = 1:app.N
                    if ((app.H(r,c) >= app.HLow && app.H(r,c) <= app.HUp) && (app.S(r,c) >= app.SLow && app.S(r,c) <= app.SUp) && (app.I(r,c) >= app.ILow && app.I(r,c) <= app.IUp))
                        app.ModImg(r,c,1:3) = app.OrigImg(r,c,1:3); %1:3 because need H,S,& I values at (r,c)
                    else
                        app.ModImg(r,c,1:3) = 0.5; %make HSI value at (r,c) = gray
                    end
%                     disp(c)
                end
%                 disp(r)
            end
            imshow(app.ModImg,'Parent',app.ModAxes); %display modified img
%             disp(app.HUp);
%             disp(app.HLow);
%             disp(app.SUp);
%             disp(app.SLow);
%             disp(app.IUp);
%             disp(app.ILow);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Load = uimenu(app.UIFigure);
            app.Load.MenuSelectedFcn = createCallbackFcn(app, @LoadMenuSelected, true);
            app.Load.Text = 'Load Image';
            app.Save = uimenu(app.UIFigure);
            app.Save.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.Save.Text = 'Save Image';
            app.OrigAxes = uiaxes(app.UIFigure);
            title(app.OrigAxes, 'Original Image')
            app.OrigAxes.XTick = [];
            app.OrigAxes.YTick = [];
            app.ModAxes = uiaxes(app.UIFigure);
            title(app.ModAxes, 'Modified Image')
            app.ModAxes.XTick = [];
            app.ModAxes.YTick = [];
            app.HueUpperLimitLabel = uilabel(app.UIFigure);
            app.HueUpperLimitLabel.Text = 'Hue Upper Limit';
            app.HueUpper = uislider(app.UIFigure);
            app.HueUpper.Limits = [0 1];
            app.HueUpper.ValueChangedFcn = createCallbackFcn(app, @HueUpperValueChanged, true);
            app.HueUpper.Value = 1;
            app.HueLowerLimitSliderLabel = uilabel(app.UIFigure);
            app.HueLowerLimitSliderLabel.Text = 'Hue Lower Limit';
            app.HueLower = uislider(app.UIFigure);
            app.HueLower.Limits = [0 1];
            app.HueLower.ValueChangedFcn = createCallbackFcn(app, @HueLowerValueChanged, true);
            app.SaturationUpperLimitSliderLabel = uilabel(app.UIFigure);
            app.SaturationUpperLimitSliderLabel.Text = 'Saturation Upper Limit';
            app.SatUpper = uislider(app.UIFigure);
            app.SatUpper.Limits = [0 1];
            app.SatUpper.ValueChangedFcn = createCallbackFcn(app, @SatUpperValueChanged, true);
            app.SatUpper.Value = 1;
            app.SaturationLowerLimitLabel = uilabel(app.UIFigure);
            app.SaturationLowerLimitLabel.Text = 'Saturation Lower Limit';
            app.SatLower = uislider(app.UIFigure);
            app.SatLower.Limits = [0 1];
            app.SatLower.ValueChangedFcn = createCallbackFcn(app, @SatLowerValueChanged, true);
            app.IntensityUpperLimitLabel = uilabel(app.UIFigure);
            app.IntensityUpperLimitLabel.Text = 'Intensity Upper Limit';
            app.IntensityUpper = uislider(app.UIFigure);
            app.IntensityUpper.Limits = [0 1];
            app.IntensityUpper.ValueChangedFcn = createCallbackFcn(app, @IntensityUpperValueChanged, true);
            app.IntensityUpper.Value = 1;
            app.IntensityLowerLimitLabel = uilabel(app.UIFigure);
            app.IntensityLowerLimitLabel.Text = 'Intensity Lower Limit';
            app.IntensityLower = uislider(app.UIFigure);
            app.IntensityLower.Limits = [0 1];
            app.IntensityLower.ValueChangedFcn = createCallbackFcn(app, @IntensityLowerValueChanged, true);
            app.SegmentButton = uibutton(app.UIFigure, 'push');
            app.SegmentButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentButtonPushed, true);
            app.SegmentButton.Text = 'Segment';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Project2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end